classdef Ratalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure      matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        videoPanel      matlab.ui.container.Panel
        vidGrid         matlab.ui.container.GridLayout
        vid             matlab.ui.control.Image
        selectionPanel  matlab.ui.container.Panel
        fileListGrid    matlab.ui.container.GridLayout
        fileButtonGrid  matlab.ui.container.GridLayout
        seg             matlab.ui.control.Button
        touch           matlab.ui.control.Button
        fileList        matlab.ui.control.ListBox
        slidePanel      matlab.ui.container.Panel
        slide           matlab.ui.control.Slider
        controlPanel    matlab.ui.container.GridLayout
        vidControls     matlab.ui.container.GridLayout
        prevFrame       matlab.ui.control.Button
        nextFrame       matlab.ui.control.Button
        back1           matlab.ui.control.Button
        for1            matlab.ui.control.Button
        back10          matlab.ui.control.Button
        for10           matlab.ui.control.Button
        speed           matlab.ui.control.DropDown
        play            matlab.ui.control.Button
        segPanel        matlab.ui.container.Panel
        segControls     matlab.ui.container.GridLayout
        doneSeg         matlab.ui.control.Button
        start           matlab.ui.control.Button
        stop            matlab.ui.control.Button
        anIDLabel       matlab.ui.control.Label
        anID            matlab.ui.control.EditField
        touchPanel      matlab.ui.container.Panel
        touchControls   matlab.ui.container.GridLayout
        lw              matlab.ui.control.Button
        bw              matlab.ui.control.Button
        rw              matlab.ui.control.Button
        lf              matlab.ui.control.Button
        bf              matlab.ui.control.Button
        rf              matlab.ui.control.Button
        undo            matlab.ui.control.Button
        unkn            matlab.ui.control.Button
        next            matlab.ui.control.Button
    end

    
    properties (Access = private)
        data    %General Info
        video   %Video Info 
        table   %Segs and Touches to save
    end
    
    methods (Access = private)
        
        function playVid(app) %Actual function for playing video via loop
            if app.video.play
                app.vid.Visible = 'on';
            end
            app.slide.Visible = 'on';
            
            while app.video.play 
                %Make sure we have a frame available
                if ~hasFrame(app.video.vid) 
                    app.play.Enable = 'off';
                    app.play.Text = 'Play';
                    app.nextFrame.Enable = 'on';
                    app.prevFrame.Enable = 'on';
                    app.video.play = 0;
                    break; 
                end
                
                %Stay within segment bounds if applicable
                if ~app.data.segPlay
                    if app.video.vid.CurrentTime < app.video.limits(1)
                        app.video.vid.CurrentTime = app.video.limits(1);
                    end
                    if app.video.vid.CurrentTime >= app.video.limits(2)
                        app.video.vid.CurrentTime = app.video.limits(2);
                        app.play.Enable = 'off';
                        app.play.Text = 'Play';
                        app.nextFrame.Enable = 'on';
                        app.prevFrame.Enable = 'on';
                        app.video.play = 0;
                        break; 
                    end
                end
                
                app.video.time = tic; 
                frame = readFrame(app.video.vid);
                app.vid.ImageSource = frame;
                elapsedTime = toc(app.video.time); 
                pauseTime = 1/(app.video.vid.FrameRate*str2double(app.speed.Value(1:end-1))) - elapsedTime;
                app.slide.Value = app.video.vid.CurrentTime;
                
                if pauseTime > 0
                    pause(pauseTime);
                else
                    drawnow; 
                end
            end
            if app.data.shutDown
                mainFigureCloseRequest(app)
            end
        end
        

    function export(app) % To write out data to text file
    
        if isempty(app.table) || (isempty(app.table.segs) && isempty(app.table.touches))
            return;
        end
    
        fullSavePath = fullfile(app.data.basePath, 'UserData.txt');
        fileID = fopen(fullSavePath, 'w');
    
        try 
            fprintf(fileID, '==============================================\r\n');
            fprintf(fileID, ' Data Export\r\n');
            fprintf(fileID, ' Exported on: %s\r\n', datestr(now, 'yyyy-mm-dd HH:MM:SS'));
            fprintf(fileID, '==============================================\r\n\r\n');
            
            % Collect all unique filenames
            allFiles = {};
            if ~isempty(app.table.segs),    allFiles = [allFiles; app.table.segs(:,1)]; end
            if ~isempty(app.table.touches), allFiles = [allFiles; app.table.touches(:,1)]; end
            uniqueFiles = unique(allFiles);
            
            % === Main Loop (Per File) ===
            for i = 1:numel(uniqueFiles)
                currentFile = uniqueFiles{i};
                fprintf(fileID, 'FILE: %s\r\n', currentFile);
                fprintf(fileID, '----------------------------------------------\r\n\r\n');
                
                % Find all segments for this file
                if isempty(app.table.segs)
                    segsForFileIdx = [];
                else
                    segsForFileIdx = find(strcmp(app.table.segs(:,1), currentFile));
                end
                
                if isempty(segsForFileIdx)
                    fprintf(fileID, ' (No segments recorded for this file)\r\n');
                else
                    % Sort segments by start time
                    startTimes = cell2mat(app.table.segs(segsForFileIdx,3));
                    [~, sortOrder] = sort(startTimes);
                    sortedSegIndices = segsForFileIdx(sortOrder);
    
                    % === Segment Loop ===
                    for j = 1:numel(sortedSegIndices)
                        segIndex = sortedSegIndices(j);
                        segRow = app.table.segs(segIndex,:);
                        
                        videoName = segRow{1};
                        ratName   = segRow{2};
                        startT    = segRow{3};
                        stopT     = segRow{4};
    
                        fprintf(fileID, ' --- Segment ID: %s (Start: %.3fs, Stop: %.3fs) ---\r\n', ...
                                ratName, startT, stopT);
    
                        % --- Find touches that fall in this segment ---
                        if isempty(app.table.touches)
                            touchesForSegIdx = [];
                        else
                            tVid  = app.table.touches(:,1);
                            tRat  = app.table.touches(:,2);
                            tTime = cell2mat(app.table.touches(:,3));
    
                            % same video & rat, within [start, stop]
                            touchesForSegIdx = find(strcmp(tVid, videoName) & ...
                                                    strcmp(tRat, ratName) & ...
                                                    tTime >= startT & tTime <= stopT);
                        end
    
                        if isempty(touchesForSegIdx)
                            fprintf(fileID, '  (No touches recorded for this segment)\r\n');
                        else
                            % Sort touches by timestamp
                            touchTimes = cell2mat(app.table.touches(touchesForSegIdx,3));
                            [~, order] = sort(touchTimes);
                            touchesForSegIdx = touchesForSegIdx(order);
    
                            % --- Touch Loop ---
                            for k = 1:numel(touchesForSegIdx)
                                tRow = app.table.touches(touchesForSegIdx(k),:);
                                fprintf(fileID, '  Timestamp: %.3fs, Type: %s\r\n', ...
                                        tRow{3}, tRow{4});
                            end
                        end
                        fprintf(fileID, '\r\n'); % space after each segment
                    end
                end
                fprintf(fileID, '\r\n'); % space after each file
            end
        end
    
        fclose(fileID);
    end

        
    function out = trimParenths(~,str)
        idx = strfind(str, '(');
        if ~isempty(idx)
            out = str(1:idx(1)-3);
        else
            out = str;
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Manage Interface
            app.videoPanel.Visible = 'on';
            app.selectionPanel.Visible = 'on';
            app.controlPanel.Visible = 'off';  
            app.slide.Visible = 'off';
            app.speed.Value = '1x';
            app.data.shutDown = 0;
            app.seg.Enable = 'on';
            app.data.multTouchFiles = 0;
            
            %Load or create table
            if isdeployed
                [~, result] = system('path');
                app.data.basePath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else
                app.data.basePath = fileparts(mfilename('fullpath'));
            end
    
            if isempty(app.table)
                try
                    load([app.data.basePath '\VideoData.mat'],'Data');
                    app.table = Data;
                catch
                    app.table.segs = {}; app.table.touches = {};
                end
            else
                export(app)
            end
                        
            %Find Video Files
            dirContents = dir(app.data.basePath);
            folderNames = {dirContents([dirContents.isdir]).name};
            videoFolderIdx = find(strcmpi('videos', folderNames), 1);
        
            if isempty(videoFolderIdx)
                msg = sprintf('Could not find a "videos" or "Videos" subfolder in:\n%s', app.data.basePath);
                uialert(app.mainFigure, msg, 'Folder Not Found');
                app.fileList.Items = {'No videos folder found'};
                app.fileList.Visible = 'on';
                return;
            end
            
            app.data.videoPath = fullfile(app.data.basePath, folderNames{videoFolderIdx});
            videoExtensions = {'*.mp4', '*.avi', '*.mov', '*.mkv', '*.mpg', '*.wmv'};
            allVideoFiles = [];
            
            for i = 1:length(videoExtensions)
                files = dir(fullfile(app.data.videoPath, videoExtensions{i}));
                if ~isempty(files)
                    allVideoFiles = [allVideoFiles; files]; %#ok<AGROW>
                end
            end
    
            if isempty(allVideoFiles)
                app.fileList.Items = {'No videos found'};
                app.fileList.Visible = 'on';
                app.fileList.Value = {}; 
            else
                fileNames = {allVideoFiles.name};
                app.fileList.Items = fileNames;
                app.fileList.Visible = 'on';
                segmentedFiles = {}; touchedFiles = {};
                if ~isempty(app.table.segs)
                    segmentedFiles = app.table.segs(:, 1); 
                end
                if ~isempty(app.table.touches)
                    touchedFiles = app.table.touches(:, 1); 
                end
                isTouched = ismember(fileNames, touchedFiles);
                isSegmented = ismember(fileNames, segmentedFiles);
                displayNames = fileNames; 
                for i = 1:length(displayNames)
                    name = displayNames{i};  
                
                    if isSegmented(i)
                        name = [name '  (Segmented - ' ...
                            num2str(sum(strcmp(app.table.segs(:,1), displayNames{i}))) ')'];%#ok<AGROW>
                    end
                
                    if isTouched(i)
                        % compute x/n
                        n = sum(strcmp(app.table.segs(:,1), displayNames{i}));
                        x = sum(arrayfun(@(a,b) ...
                            any(cell2mat(app.table.touches(strcmp(app.table.touches(:,1),displayNames{i}),3))>=a & ...
                                cell2mat(app.table.touches(strcmp(app.table.touches(:,1),displayNames{i}),3))<=b), ...
                            cell2mat(app.table.segs(strcmp(app.table.segs(:,1),displayNames{i}),3)), ...
                            cell2mat(app.table.segs(strcmp(app.table.segs(:,1),displayNames{i}),4))));
                
                        name = [name '  (Touches - ' num2str(x) '/' num2str(n) ')'];%#ok<AGROW>
                    end
                
                    displayNames{i} = name;  
                end
                app.fileList.Items = displayNames;
                app.fileList.Visible = 'on';
            end
    
            app.vid.Visible = 'off';
        end

        % Button pushed function: seg
        function segButtonPushed(app, event)
            %Play Video
            app.selectionPanel.Visible = 'off';
            drawnow
            app.data.fileName = char(app.fileList.Value);
            idx = strfind(app.data.fileName, '  (');
            if ~isempty(idx)
                app.data.cleanFileName = app.data.fileName(1:idx(1)-1);
            else
                app.data.cleanFileName = app.data.fileName;
            end

            if contains(app.data.fileName,'Segmented')
                choice = questdlg('Existing Segments Detected','','Append Segments','Override Segments','Cancel','Append Segments');
                if strcmp(choice,'Cancel')
                    app.selectionPanel.Visible = 'on';
                    drawnow
                    return
                end
                if strcmp(choice,'Override Segments')
                    if ~isempty(app.table.segs)
                        segmentedFiles = app.table.segs(:, 1); 
                        toErase = ismember(segmentedFiles,app.data.cleanFileName);
                        app.table.segs(toErase,:) = [];
                    end
                end
            end

            file = [app.data.videoPath '\' app.data.cleanFileName];
            try
                app.video.vid = VideoReader(file);
            catch ME
                msg = sprintf(['Unable to load video file: %s\n\n', ...
                                 'Error: %s\n\n', ...
                                 'This may be due to a missing or incompatible video codec, or the file may be corrupt. ' ...
                                 'Please verify the file and ensure necessary codecs are installed.'], ...
                                 app.data.cleanFileName, ME.message);
                uialert(app.mainFigure, msg, 'Video Load Error');
                return;
            end
            app.slide.Limits = [0 app.video.vid.Duration];
            
            %Open Controls
            app.videoPanel.Visible = 'on';
            app.vid.Visible = 'off';
            app.selectionPanel.Visible = 'off';
            app.controlPanel.Visible = 'on';
            app.vidControls.Visible = 'on';
            app.segPanel.Visible = 'on';
            app.touchPanel.Visible = 'off';
            app.video.play = 0;
            app.play.Text = 'Play';
            app.start.Text = 'Start Time';
            app.stop.Text = 'Stop Time';
            app.anID.Value = '';
            app.doneSeg.Text = 'Save Segments and Finish';
            app.stop.Enable = 0;
            app.start.Enable = 0;
            app.slide.Visible = 'off';
            app.slide.Value = 0;
            
            app.data.segPlay = 1;
            playVid(app)
        end

        % Button pushed function: touch
        function touchButtonPushed(app, event)
            %Either we are establishing the setup for touch classification 'Classify Touches' or...
            if strcmp(app.touch.Text,'Classify Touches')
                app.data.touchFiles = app.fileList.Value;
                app.data.touchFileSeq = randperm(numel(app.data.touchFiles));
                app.data.curFile = 0;
                app.data.cleanTouchFiles = cellfun(@(s) trimParenths(app,s),app.data.touchFiles,'UniformOutput',false);
                app.data.unSegs = [];
                
                %Make user name rats for any non-segmented files
                fullSegs = find(~contains(app.data.touchFiles,'Segmented'));
                if ~isempty(fullSegs)
                    if size(fullSegs,2) > 1
                        choice = questdlg('Use the same Animal ID for all files?','','Yes','No','Yes');
                    else
                        choice = 'No';
                    end
                    if strcmp(choice,'Yes')
                        newName = inputdlg('Each clip will be treated as one segment. Please enter Animal ID for all files: ','Animal ID');
                        for i = 1:length(fullSegs)
                            try app.table.segs = [app.table.segs; [app.data.cleanTouchFiles(fullSegs(i)), newName{:}, {0}, {inf}]]; end %#ok<*TRYNC>
                        end
                    else
                        for i = 1:length(fullSegs)
                            newName = inputdlg(['Entire clip will be treated as one segment. Please enter Animal ID for file - ' app.data.touchFiles{fullSegs(i)} ': '],'Animal ID');
                            try app.table.segs = [app.table.segs; [app.data.cleanTouchFiles(fullSegs(i)), newName{:}, {0}, {inf}]]; end
                        end
                    end
                end
                app.data.touchRats = unique(app.table.segs(ismember(app.table.segs(:,1),app.data.cleanTouchFiles),2));
                     
                %Handle existing touch classifications
                if any(contains(app.data.touchFiles,'Touches'))
                    choice = questdlg('Existing Touch Classifications Detected','','Classify Remaining Segments','Override Existing Classified Segments','Cancel','Classify Remaining Segments');
                    if strcmp(choice,'Cancel')
                        return
                    end
                    if strcmp(choice,'Override Existing Classified Segments')
                        if ~isempty(app.table.touches)
                            app.table.touches(ismember(app.table.touches(:, 1), app.data.cleanTouchFiles),:) = [];
                        end
                    else 
                        %Find segments with existing touches to exclude from appending touches to
                        app.data.unSegs = find(cellfun(@(v,r,a,b) any(strcmp(app.table.touches(:,1),v) & strcmp(app.table.touches(:,2),r)...
                        & cell2mat(app.table.touches(:,3))>=a & cell2mat(app.table.touches(:,3))<=b), ...
                        app.table.segs(:,1), app.table.segs(:,2), num2cell(cell2mat(app.table.segs(:,3))), num2cell(cell2mat(app.table.segs(:,4)))));
                    end 
                end
                
                %Randomize Segments?
                choice = questdlg('Randomize Segment Order?','','Yes','No','Cancel','Yes');
                if strcmp(choice,'Cancel'); return; end
                app.data.randSegs = strcmp(choice,'Yes');
                
                %Select Rats to run
                listRats = cell(1, size(app.data.touchRats, 1));
                for rat = 1:size(app.data.touchRats, 1)
                    % Find indices where the Rat matches AND the File matches selected files
                    relevantIdx = find(strcmp(app.table.segs(:,2), app.data.touchRats{rat}) & ...
                                       ismember(app.table.segs(:,1), app.data.cleanTouchFiles));
                    
                    % Calculate counts based only on those relevant indices
                    remainingCount = sum(ismember(relevantIdx, app.data.unSegs));
                    totalCount = numel(relevantIdx);
                    
                    % Create string
                    listRats{rat} = [app.data.touchRats{rat} ' : ' num2str(remainingCount) ' / ' num2str(totalCount)];
                end
                app.fileList.Items = listRats;
                app.touch.Text = 'Continue';
                app.seg.Visible = 'off';
                drawnow
                
                 % ...we are selecting rats and loading everything 
            else
                app.data.curFile = app.data.curFile + 1;
                app.data.curVideoName = app.data.cleanTouchFiles{app.data.touchFileSeq(app.data.curFile)};
                
                %Load video
                try
                    app.video.vid = VideoReader([app.data.videoPath '\' app.data.curVideoName]);
                catch ME
                    msg = sprintf(['Unable to load video fileapp.slide.Limits = [0 app.video.vid.Duration];: %s\n\n', ...
                                     'Error: %s\n\n', ...
                                     'This may be due to a missing or incompatible video codec, or the file may be corrupt. ' ...
                                     'Please verify the file and ensure necessary codecs are installed.'], ...
                                     app.data.curVideoName, ME.message);
                    uialert(app.mainFigure, msg, 'Video Load Error');
                    return;
                end
                app.slide.Limits = [0 app.video.vid.Duration];

                %Open Controls
                app.videoPanel.Visible = 'on';
                app.vid.Visible = 'off';
                app.selectionPanel.Visible = 'off';
                app.controlPanel.Visible = 'on';
                app.vidControls.Visible = 'on';
                app.segPanel.Visible = 'off';
                app.touchPanel.Visible = 'on';
                app.video.play = 0;
                app.play.Text = 'Play';
                app.play.Enable = 'on';
                app.slide.Visible = 'off';
                app.slide.Value = 0;

                %Setup segment order
                relSegs = find(and(ismember(app.table.segs(:,1),app.data.curVideoName),ismember(app.table.segs(:,2),app.data.touchRats(ismember(app.fileList.Items, app.fileList.Value)))));
                relSegs = setdiff(relSegs,app.data.unSegs);
               
                if isempty(relSegs)
                    uialert(app.mainFigure, 'All Valid Segments Already Contain Touches', 'All Valid Segments Already Contain Touches','CloseFcn',@(src, event) uiresume(app.mainFigure))
                    uiwait(app.mainFigure);
                    app.data.seg =1;  app.data.segOrder=0;
                    nextPushed(app)
                    return
                end
                
                if app.data.randSegs
                    app.data.segOrder = relSegs(randperm(numel(relSegs)));
                else
                    app.data.segOrder = relSegs;
                end
                app.data.seg = 1;
                if isinf(app.table.segs{app.data.segOrder(app.data.seg),4})
                    app.table.segs{app.data.segOrder(app.data.seg),4} = app.video.vid.Duration;
                end
                app.video.limits = [app.table.segs{app.data.segOrder(app.data.seg),3} app.table.segs{app.data.segOrder(app.data.seg),4}];
                app.data.segPlay = 0;
                
                %Adjust GUI
                app.next.Text = ['End ' num2str(app.data.seg) '/' num2str(numel(app.data.segOrder))];            
                app.video.vid.CurrentTime = app.video.limits(1);
                app.slide.Value = app.video.limits(1);
                frame = readFrame(app.video.vid);
                app.vid.ImageSource = frame;
                if hasFrame(app.video.vid)
                    app.play.Enable = 1;
                end
                app.vid.Visible = 'on';
                playVid(app)
            end

        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            if strcmp(app.play.Text,'Play')
                app.play.Text = 'Pause';
                app.video.play = 1;
                app.nextFrame.Enable = 0;
                app.prevFrame.Enable = 0;
                playVid(app)
            else
                app.play.Text = 'Play';
                app.video.play = 0;  
                app.nextFrame.Enable = 1;
                app.prevFrame.Enable = 1;
            end
        end

        % Button pushed function: for1
        function for1ButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = min(app.video.vid.Duration-0.1, app.video.vid.CurrentTime +1);
            else
                app.video.vid.CurrentTime = min(app.video.limits(2)-0.1, app.video.vid.CurrentTime +1);
            end
            app.slide.Value = app.video.vid.CurrentTime;
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
        end

        % Button pushed function: back10
        function back10ButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = max(0, app.video.vid.CurrentTime -10);
            else
                app.video.vid.CurrentTime = max(app.video.limits(1), app.video.vid.CurrentTime -10);
            end
            app.slide.Value = app.video.vid.CurrentTime;
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
            if hasFrame(app.video.vid)
                app.play.Enable = 1;
            end
        end

        % Button pushed function: for10
        function for10ButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = min(app.video.vid.Duration-0.1, app.video.vid.CurrentTime +10);
            else
                app.video.vid.CurrentTime = min(app.video.limits(2)-0.1, app.video.vid.CurrentTime +10);
            end
            app.slide.Value = app.video.vid.CurrentTime;
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
        end

        % Button pushed function: back1
        function back1ButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = max(0, app.video.vid.CurrentTime -1);
            else
                app.video.vid.CurrentTime = max(app.video.limits(1), app.video.vid.CurrentTime -1);
            end
            app.slide.Value = app.video.vid.CurrentTime;
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
            if hasFrame(app.video.vid)
                app.play.Enable = 1;
            end
        end

        % Value changed function: slide
        function slideValueChanged(app, event)
            if ~app.data.segPlay
                if app.slide.Value > app.video.limits(2)
                    app.video.vid.CurrentTime = app.video.limits(2);
                    app.slide.Value = app.video.limits(2);
                end
                if app.slide.Value < app.video.limits(1)
                    app.video.vid.CurrentTime = app.video.limits(1);
                    app.slide.Value = app.video.limits(1);
                end
            end
            app.video.vid.CurrentTime = app.slide.Value;
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
            if hasFrame(app.video.vid)
                app.play.Enable = 1;
            end
        end

        % Button pushed function: nextFrame
        function nextFrameButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = min(app.video.vid.Duration, app.video.vid.CurrentTime + 1/app.video.vid.FrameRate);
            else
                app.video.vid.CurrentTime = min(app.video.limits(2), app.video.vid.CurrentTime + 1/app.video.vid.FrameRate);
            end
            try %Don't try if no more frames
                frame = readFrame(app.video.vid);
                app.vid.ImageSource = frame;
            catch
            end
        end

        % Button pushed function: prevFrame
        function prevFrameButtonPushed(app, event)
            if app.data.segPlay
                app.video.vid.CurrentTime = max(0,app.video.vid.CurrentTime - 3/app.video.vid.FrameRate); %3 actaully results in 1
            else
                app.video.vid.CurrentTime = max(app.video.limits(1),app.video.vid.CurrentTime - 3/app.video.vid.FrameRate);
            end
            frame = readFrame(app.video.vid);
            app.vid.ImageSource = frame;
            if hasFrame(app.video.vid)
                app.play.Enable = 1;
            end
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            if strcmp(app.start.Text,'Start Time')
                if strcmp(app.stop.Text,'Stop Time')
                    %Check for overlap - display time if no overlap
                    if isempty(app.table.segs)
                        relSegs = {};
                    else
                        relSegs = app.table.segs(strcmp(app.table.segs(:,1), app.data.cleanFileName), :);
                    end
                    if isempty(relSegs)
                        overlap = false;
                    else
                        existingStarts = cell2mat(relSegs(:,3)); existingStops  = cell2mat(relSegs(:,4));
                        overlap = any(app.video.vid.CurrentTime < existingStops & app.video.vid.CurrentTime  > existingStarts);
                    end
                    if ~overlap
                        app.start.Text = num2str(app.video.vid.CurrentTime);
                        app.doneSeg.Enable = 'off';
                    else
                        app.start.Text = 'Overlapping Segments';
                        app.start.BackgroundColor = [0.8 0.1 0.1];
                        pause(1.25)
                        app.start.Text = 'Start Time';
                        app.start.BackgroundColor = [0.96 0.96 0.96];
                    end           
                else
                    if str2double(app.stop.Text) > app.video.vid.CurrentTime
                        %Check for overlap - record if no overlap
                        if isempty(app.table.segs)
                            relSegs = {};
                        else
                            relSegs = app.table.segs(strcmp(app.table.segs(:,1), app.data.cleanFileName), :);
                        end
            
                        if isempty(relSegs)
                            overlap = false;
                        else
                            existingStarts = cell2mat(relSegs(:,3)); existingStops  = cell2mat(relSegs(:,4));
                            overlap = any(app.video.vid.CurrentTime < existingStops & str2double(app.stop.Text)  > existingStarts);
                        end
            
                        if ~overlap
                            app.table.segs = [app.table.segs; {app.data.cleanFileName, app.anID.Value, app.video.vid.CurrentTime, str2double(app.stop.Text)}];
                            app.start.Text = 'Recorded';
                        else
                            app.start.Text = 'Not Saved - Overlapping Segments';
                            app.start.BackgroundColor = [0.8 0.1 0.1];
                            pause(0.75)
                        end
                    else
                        app.start.Text = 'Incompatible Times';
                        app.start.BackgroundColor = [0.8 0.1 0.1];
                        pause(0.75)
                    end
                    pause(0.5)
                    app.start.Text = 'Start Time';
                    app.stop.Text  = 'Stop Time';
                    app.doneSeg.Enable = 'on';
                    app.start.BackgroundColor = [0.96 0.96 0.96];
                end
            else
                app.doneSeg.Enable = 'on';
                app.start.Text = 'Start Time';
            end
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            if strcmp(app.stop.Text,'Stop Time')
                if strcmp(app.start.Text,'Start Time')
                    %Check for overlap - display time if no overlap
                    if isempty(app.table.segs)
                        relSegs = {};
                    else
                        relSegs = app.table.segs(strcmp(app.table.segs(:,1), app.data.cleanFileName), :);
                    end
        
                    if isempty(relSegs)
                        overlap = false;
                    else
                        existingStarts = cell2mat(relSegs(:,3)); existingStops  = cell2mat(relSegs(:,4));
                        overlap = any(app.video.vid.CurrentTime < existingStops & app.video.vid.CurrentTime  > existingStarts);
                    end
        
                    if ~overlap
                        app.stop.Text = num2str(app.video.vid.CurrentTime);
                        app.doneSeg.Enable = 'off';
                    else
                        app.stop.Text = 'Overlapping Segments';
                        app.stop.BackgroundColor = [0.8 0.1 0.1];
                        pause(1.25)
                        app.stop.Text = 'Stop Time';
                        app.stop.BackgroundColor = [0.96 0.96 0.96];
                    end
                else
                    if app.video.vid.CurrentTime > str2double(app.start.Text)
                        %Check for overlap - record if no overlap
                        if isempty(app.table.segs)
                            relSegs = {};
                        else
                            relSegs = app.table.segs(strcmp(app.table.segs(:,1), app.data.cleanFileName), :);
                        end
            
                        if isempty(relSegs)
                            overlap = false;
                        else
                            existingStarts = cell2mat(relSegs(:,3)); existingStops  = cell2mat(relSegs(:,4));
                            overlap = any(str2double(app.start.Text) < existingStops & app.video.vid.CurrentTime  > existingStarts);
                        end
            
                        if ~overlap
                            app.table.segs = [app.table.segs; {app.data.cleanFileName, app.anID.Value, str2double(app.start.Text), app.video.vid.CurrentTime}];
                            app.stop.Text = 'Recorded';
                        else
                            app.stop.Text = 'Not Saved - Overlapping Segments';
                            app.stop.BackgroundColor = [0.8 0.1 0.1];
                            pause(0.75)
                        end
                    else
                        app.stop.Text = 'Incompatible Times';
                        app.stop.BackgroundColor = [0.8 0.1 0.1];
                        pause(0.75)
                    end
                        pause(0.5)
                        app.stop.Text = 'Stop Time';
                        app.start.Text = 'Start Time';
                        app.doneSeg.Enable = 'on';
                        app.stop.BackgroundColor = [0.96 0.96 0.96];
                end
            else
                app.doneSeg.Enable = 'on';
                app.stop.Text = 'Stop Time';
            end
        end

        % Value changed function: anID
        function anIDValueChanged(app, event)
            if isempty(app.anID.Value)
                app.start.Enable = 0;
                app.stop.Enable = 0;
            else
                app.start.Enable = 1;
                app.stop.Enable = 1;
            end
            
        end

        % Button pushed function: doneSeg
        function doneSegButtonPushed(app, event)
            fullSavePath = fullfile(app.data.basePath, 'VideoData.mat');
            Data = app.table;
            save(fullSavePath,'Data')
            %Create user friendly version of .mat data
            segs=Data.segs; if istable(segs),segs=table2cell(segs);end
            tchs=Data.touches; if istable(tchs),tchs=table2cell(tchs);end
            
            if isempty(segs), DataNested=struct('Filename',{},'Subject',{}); return; end    
            if isempty(tchs), tchs=cell(0,4); end                                          
            if size(segs,2)<4, segs(:,end+1:4)={[]}; end
            if size(tchs,2)<4, tchs(:,end+1:4)={[]}; end
            
            files=unique(segs(:,1),'stable'); DataNested=struct('Filename',{},'Subject',{});
            for i=1:numel(files)
                fn=files{i}; sf=strcmp(segs(:,1),fn); tf=strcmp(tchs(:,1),fn);
                DataNested(i).Filename=fn; subs=unique(segs(sf,2),'stable');
                DataNested(i).Subject=repmat(struct('ID','', 'Segment',[]), numel(subs),1);
            
                for m=1:numel(subs)
                    sub=subs{m}; DataNested(i).Subject(m).ID=sub;
                    ss = sf & strcmp(segs(:,2),sub); ts = tf & strcmp(tchs(:,2),sub);
            
                    s=cell2mat(segs(ss,3)); e=cell2mat(segs(ss,4)); [s,o]=sort(s); e=e(o);
                    tt=cell2mat(tchs(ts,3)); tl=tchs(ts,4);
            
                    Seg=repmat(struct('TimeRange',[],'Touch',struct('Time',{},'Touch',{})), numel(s),1);
                    for j=1:numel(s)
                        Seg(j).TimeRange=[s(j) e(j)];
                        ix=find(tt>=s(j) & tt<=e(j));
                        if ~isempty(ix), Seg(j).Touch=struct('Time',num2cell(tt(ix)),'Touch',tl(ix)); end
                    end
                    DataNested(i).Subject(m).Segment=Seg;
                end
            end


            fullSavePath = fullfile(app.data.basePath, 'UserData.mat');
            save(fullSavePath,'DataNested')
            startupFcn(app)
        end

        % Button pushed function: next
        function nextPushed(app, event)
            if app.data.seg ~= numel(app.data.segOrder)
                app.data.seg = app.data.seg + 1;
                app.next.Text = ['End ' num2str(app.data.seg) '/' num2str(numel(app.data.segOrder))];
                if isinf(app.table.segs{app.data.segOrder(app.data.seg),4})
                    app.table.segs{app.data.segOrder(app.data.seg),4} = app.video.vid.Duration;
                end
                app.video.limits = [app.table.segs{app.data.segOrder(app.data.seg),3} app.table.segs{app.data.segOrder(app.data.seg),4}];
                app.video.vid.CurrentTime = app.video.limits(1);
                playVid(app)
                app.slide.Value = app.video.limits(1);
                frame = readFrame(app.video.vid);
                app.vid.ImageSource = frame;
                if hasFrame(app.video.vid)
                    app.play.Enable = 1;
                end
            else
                fullSavePath = fullfile(app.data.basePath, 'VideoData.mat');
                Data = app.table;
                save(fullSavePath,'Data')
                %Create user friendly version of .mat data
                segs=Data.segs; if istable(segs),segs=table2cell(segs);end
                tchs=Data.touches; if istable(tchs),tchs=table2cell(tchs);end
                
                if isempty(segs), DataNested=struct('Filename',{},'Subject',{}); return; end    
                if isempty(tchs), tchs=cell(0,4); end                                          
                if size(segs,2)<4, segs(:,end+1:4)={[]}; end
                if size(tchs,2)<4, tchs(:,end+1:4)={[]}; end
                
                files=unique(segs(:,1),'stable'); DataNested=struct('Filename',{},'Subject',{});
                for i=1:numel(files)
                    fn=files{i}; sf=strcmp(segs(:,1),fn); tf=strcmp(tchs(:,1),fn);
                    DataNested(i).Filename=fn; subs=unique(segs(sf,2),'stable');
                    DataNested(i).Subject=repmat(struct('ID','', 'Segment',[]), numel(subs),1);
                
                    for m=1:numel(subs)
                        sub=subs{m}; DataNested(i).Subject(m).ID=sub;
                        ss = sf & strcmp(segs(:,2),sub); ts = tf & strcmp(tchs(:,2),sub);
                
                        s=cell2mat(segs(ss,3)); e=cell2mat(segs(ss,4)); [s,o]=sort(s); e=e(o);
                        tt=cell2mat(tchs(ts,3)); tl=tchs(ts,4);
                
                        Seg=repmat(struct('TimeRange',[],'Touch',struct('Time',{},'Touch',{})), numel(s),1);
                        for j=1:numel(s)
                            Seg(j).TimeRange=[s(j) e(j)];
                            ix=find(tt>=s(j) & tt<=e(j));
                            if ~isempty(ix), Seg(j).Touch=struct('Time',num2cell(tt(ix)),'Touch',tl(ix)); end
                        end
                        DataNested(i).Subject(m).Segment=Seg;
                    end
                end
                fullSavePath = fullfile(app.data.basePath, 'UserData.mat');
                save(fullSavePath,'DataNested')
                app.video.play =0;

                if app.data.curFile == numel(app.data.touchFiles)
                    app.touch.Text = 'Classify Touches';
                    app.seg.Visible = 'on';
                    startupFcn(app)
                else
                    touchButtonPushed(app)
                end
            end
        end

        % Button pushed function: lw
        function lwButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Left Wall'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: bw
        function bwButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Both Wall'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: rw
        function rwButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Right Wall'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: lf
        function lfButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Left Floor'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: bf
        function bfButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Both Floor'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: rf
        function rfButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Right Floor'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: unkn
        function unknButtonPushed(app, event)
            app.table.touches = [app.table.touches; {app.data.curVideoName, app.table.segs{app.data.segOrder(app.data.seg),2},  app.video.vid.CurrentTime, 'Unknown'}];
            app.video.limits(1) = app.video.vid.CurrentTime;
        end

        % Button pushed function: undo
        function undoButtonPushed(app, event)
            if ~isempty(app.table.touches)
                app.table.touches(end,:) = [];
                if ~isempty(app.table.touches)
                    app.video.limits(1) = app.table.touches{end,3};
                else
                    app.video.limits(1) = app.table.segs{app.data.segOrder(app.data.seg),3};
                end
            end
        end

        % Close request function: mainFigure
        function mainFigureCloseRequest(app, event)
            try
                if app.video.play
                    app.video.play = 0;
                    app.data.shutDown =1;
                else
                    export(app)
                    delete(app)
                end
            catch
                export(app)
                delete(app)
            end
        end

        % Value changed function: fileList
        function fileListValueChanged(app, event)
            if numel(app.fileList.Value) > 1
                app.seg.Enable = 'off';
            else
                app.seg.Enable = 'on';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'UI Figure';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @mainFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.mainFigure);
            app.videoPanel = uipanel(app.GridLayout);
            app.vidGrid = uigridlayout(app.videoPanel);
            app.vid = uiimage(app.vidGrid);
            app.selectionPanel = uipanel(app.GridLayout);
            app.selectionPanel.Visible = 'off';
            app.fileListGrid = uigridlayout(app.selectionPanel);
            app.fileButtonGrid = uigridlayout(app.fileListGrid);
            app.seg = uibutton(app.fileButtonGrid, 'push');
            app.seg.ButtonPushedFcn = createCallbackFcn(app, @segButtonPushed, true);
            app.seg.Text = 'Segment Video';
            app.touch = uibutton(app.fileButtonGrid, 'push');
            app.touch.ButtonPushedFcn = createCallbackFcn(app, @touchButtonPushed, true);
            app.touch.Text = 'Classify Touches';
            app.fileList = uilistbox(app.fileListGrid);
            app.fileList.Multiselect = 'on';
            app.fileList.ValueChangedFcn = createCallbackFcn(app, @fileListValueChanged, true);
            app.fileList.Value = {'Item 1'};
            app.slidePanel = uipanel(app.GridLayout);
            app.slide = uislider(app.slidePanel);
            app.slide.MajorTicks = [];
            app.slide.ValueChangedFcn = createCallbackFcn(app, @slideValueChanged, true);
            app.slide.MinorTicks = [];
            app.controlPanel = uigridlayout(app.GridLayout);
            app.vidControls = uigridlayout(app.controlPanel);
            app.prevFrame = uibutton(app.vidControls, 'push');
            app.prevFrame.ButtonPushedFcn = createCallbackFcn(app, @prevFrameButtonPushed, true);
            app.prevFrame.Text = 'Prev Frame';
            app.nextFrame = uibutton(app.vidControls, 'push');
            app.nextFrame.ButtonPushedFcn = createCallbackFcn(app, @nextFrameButtonPushed, true);
            app.nextFrame.Text = 'Next Frame';
            app.back1 = uibutton(app.vidControls, 'push');
            app.back1.ButtonPushedFcn = createCallbackFcn(app, @back1ButtonPushed, true);
            app.back1.Text = '<< 1 Sec';
            app.for1 = uibutton(app.vidControls, 'push');
            app.for1.ButtonPushedFcn = createCallbackFcn(app, @for1ButtonPushed, true);
            app.for1.Text = '1 Sec >>';
            app.back10 = uibutton(app.vidControls, 'push');
            app.back10.ButtonPushedFcn = createCallbackFcn(app, @back10ButtonPushed, true);
            app.back10.Text = '<< 10 Sec';
            app.for10 = uibutton(app.vidControls, 'push');
            app.for10.ButtonPushedFcn = createCallbackFcn(app, @for10ButtonPushed, true);
            app.for10.Text = '10 Sec >>';
            app.speed = uidropdown(app.vidControls);
            app.speed.Items = {'0.5x', '1x', '2x', '3x', '5x'};
            app.speed.Value = '0.5x';
            app.play = uibutton(app.vidControls, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'Play';
            app.segPanel = uipanel(app.controlPanel);
            app.segControls = uigridlayout(app.segPanel);
            app.doneSeg = uibutton(app.segControls, 'push');
            app.doneSeg.ButtonPushedFcn = createCallbackFcn(app, @doneSegButtonPushed, true);
            app.doneSeg.Text = 'Save Segments and Finish';
            app.start = uibutton(app.segControls, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start Time';
            app.stop = uibutton(app.segControls, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = 'Stop Time';
            app.anIDLabel = uilabel(app.segControls);
            app.anIDLabel.Text = 'Animal Identifier:        ';
            app.anID = uieditfield(app.segControls, 'text');
            app.anID.ValueChangedFcn = createCallbackFcn(app, @anIDValueChanged, true);
            app.touchPanel = uipanel(app.controlPanel);
            app.touchControls = uigridlayout(app.touchPanel);
            app.lw = uibutton(app.touchControls, 'push');
            app.lw.ButtonPushedFcn = createCallbackFcn(app, @lwButtonPushed, true);
            app.lw.Text = 'Left Wall';
            app.bw = uibutton(app.touchControls, 'push');
            app.bw.ButtonPushedFcn = createCallbackFcn(app, @bwButtonPushed, true);
            app.bw.Text = 'Both Wall';
            app.rw = uibutton(app.touchControls, 'push');
            app.rw.ButtonPushedFcn = createCallbackFcn(app, @rwButtonPushed, true);
            app.rw.Text = 'Right Wall';
            app.lf = uibutton(app.touchControls, 'push');
            app.lf.ButtonPushedFcn = createCallbackFcn(app, @lfButtonPushed, true);
            app.lf.Text = 'Left Floor';
            app.bf = uibutton(app.touchControls, 'push');
            app.bf.ButtonPushedFcn = createCallbackFcn(app, @bfButtonPushed, true);
            app.bf.Text = {'Both Floor'; ''};
            app.rf = uibutton(app.touchControls, 'push');
            app.rf.ButtonPushedFcn = createCallbackFcn(app, @rfButtonPushed, true);
            app.rf.Text = {'Right Floor'; ''};
            app.undo = uibutton(app.touchControls, 'push');
            app.undo.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undo.Text = 'Undo';
            app.unkn = uibutton(app.touchControls, 'push');
            app.unkn.ButtonPushedFcn = createCallbackFcn(app, @unknButtonPushed, true);
            app.unkn.Text = 'Unknown';
            app.next = uibutton(app.touchControls, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextPushed, true);
            app.next.Text = 'Next Segment';
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ratalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end